classdef PID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPIDUIFigure   matlab.ui.Figure
        StartButton          matlab.ui.control.Button
        DesiredLevelField    matlab.ui.control.NumericEditField
        PDLabel_2            matlab.ui.control.Label
        PaneldeControlLabel  matlab.ui.control.Label
        ProcessChartAxes     matlab.ui.control.UIAxes
        TankAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MaxTankVolume = 100;       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
      % Obtener el nivel deseado en m^3
            nivel_deseado = app.DesiredLevelField.Value;

            % Verificar que el nivel deseado sea finito y real
            if ~isfinite(nivel_deseado) || nivel_deseado < 0
                uialert(app.ControlPIDUIFigure, 'El nivel deseado debe ser un número finito, real y no negativo.', 'Error');
                return;
            end

            % Parámetros del controlador PID
            Kp = 1;  % Ganancia proporcional
            Ki = 0.1;  % Ganancia integral
            Kd = 0.01;  % Ganancia derivativa

            % Crear el controlador PID
            pidController = pid(Kp, Ki, Kd);

            % Simulación del sistema
            tiempo_simulacion = 0:0.5:100; % Incremento aumentado para reducir puntos simulados
            sistema = tf(1, [1 0]); % Sistema de primer orden
            sistema_con_controlador = feedback(sistema * pidController, 1);
            entrada = nivel_deseado * ones(size(tiempo_simulacion));
            [respuesta, tiempo] = lsim(sistema_con_controlador, entrada, tiempo_simulacion);

            % Inicializar las líneas del gráfico
            hold(app.ProcessChartAxes, 'on');
            hReal = plot(app.ProcessChartAxes, tiempo(1), respuesta(1), 'b', 'LineWidth', 2);
            hDeseado = plot(app.ProcessChartAxes, tiempo, nivel_deseado * ones(size(tiempo)), 'r--', 'LineWidth', 2);
            hold(app.ProcessChartAxes, 'off');
            title(app.ProcessChartAxes, 'Control PID para la Regulación del Nivel del Tanque');
            xlabel(app.ProcessChartAxes, 'Tiempo (s)');
            ylabel(app.ProcessChartAxes, 'Nivel del Tanque (m^3)');
            legend(app.ProcessChartAxes, 'Nivel Real del Tanque', 'Nivel Deseado');
            grid(app.ProcessChartAxes, 'on');

            % Animar el llenado del tanque y la gráfica del proceso
            for i = 1:length(tiempo)
                % Actualizar la gráfica del proceso
                set(hReal, 'XData', tiempo(1:i), 'YData', respuesta(1:i));
                
                % Animar el llenado del tanque
                animateTankFill(app, respuesta(i));
                
                % Actualizar la figura periódicamente para mejorar rendimiento
                if mod(i, 5) == 0 || i == length(tiempo)
                    drawnow;
                end
                
                pause(0.05); % Reducir el tiempo de pausa para aumentar la velocidad
            end
        end

        % Función para animar el llenado del tanque
        function animateTankFill(app, nivel)
            cla(app.TankAxes);
            hold(app.TankAxes, 'on');

            % Definir las dimensiones del tanque
            tankHeight = 100; % Altura del tanque en unidades arbitrarias
            tankWidth = 50;   % Ancho del tanque en unidades arbitrarias

            % Dibujar el tanque
            rectangle(app.TankAxes, 'Position', [25, 0, tankWidth, tankHeight], 'EdgeColor', 'k', 'LineWidth', 2);

            % Dibujar el nivel de agua
            waterHeight = (nivel / app.MaxTankVolume) * tankHeight; % Escalar el nivel de agua
            rectangle(app.TankAxes, 'Position', [25, 0, tankWidth, waterHeight], 'FaceColor', 'b');

            % Configurar propiedades de los ejes
            axis(app.TankAxes, [0 100 0 100]);
            xlabel(app.TankAxes, '');
            ylabel(app.TankAxes, '');
            title(app.TankAxes, 'Nivel del Tanque');
            hold(app.TankAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ControlPIDUIFigure = uifigure('Visible', 'off');
            app.ControlPIDUIFigure.Name = 'Control PID ';
            app.ControlPIDUIFigure.Icon = fullfile(pathToMLAPP, 'water-tank_2825589.png');
            app.TankAxes = uiaxes(app.ControlPIDUIFigure);
            app.TankAxes.SubtitleFontWeight = 'bold';
            app.ProcessChartAxes = uiaxes(app.ControlPIDUIFigure);
            app.ProcessChartAxes.SubtitleFontWeight = 'bold';
            app.PaneldeControlLabel = uilabel(app.ControlPIDUIFigure);
            app.PaneldeControlLabel.Text = 'Panel de Control ';
            app.PDLabel_2 = uilabel(app.ControlPIDUIFigure);
            app.PDLabel_2.Text = 'Nivel deseado en metros cúbicos ';
            app.DesiredLevelField = uieditfield(app.ControlPIDUIFigure, 'numeric');
            app.StartButton = uibutton(app.ControlPIDUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Llenar';
            % Show the figure after all components are created
            app.ControlPIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlPIDUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPIDUIFigure)
        end
    end
end